classdef app1_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel     matlab.ui.control.Label
        QualityPredictionEditField  matlab.ui.control.EditField
        QualityPredictionEditFieldLabel  matlab.ui.control.Label
        PredictCurrentButton_2      matlab.ui.control.Button
        NetworkDropDown_2           matlab.ui.control.DropDown
        NetworkDropDown_2Label      matlab.ui.control.Label
        ClassificationPredictionMethodDropDown  matlab.ui.control.DropDown
        ClassificationPredictionMethodDropDownLabel  matlab.ui.control.Label
        NetworkDropDown             matlab.ui.control.DropDown
        NetworkDropDownLabel        matlab.ui.control.Label
        PredictCurrentButton        matlab.ui.control.Button
        RegressionPredictionMethodDropDown  matlab.ui.control.DropDown
        RegressionPredictionMethodDropDownLabel  matlab.ui.control.Label
        PrecontactskibidiButton     matlab.ui.control.Button
        BadvibesButton              matlab.ui.control.Button
        ProbeslipButton             matlab.ui.control.Button
        ToostiffButton              matlab.ui.control.Button
        AmbiguousCPButton           matlab.ui.control.Button
        PlusOneButton               matlab.ui.control.Button
        MinusOneButton              matlab.ui.control.Button
        AxisAutoButton_2            matlab.ui.control.Button
        RejectFCButton              matlab.ui.control.Button
        AcceptFCButton              matlab.ui.control.Button
        MapquantityDropDown         matlab.ui.control.DropDown
        MapquantityDropDownLabel    matlab.ui.control.Label
        AxisAutoButton              matlab.ui.control.Button
        UpdateClimitsButton         matlab.ui.control.Button
        UpperCLEditField            matlab.ui.control.NumericEditField
        UpperCLEditFieldLabel       matlab.ui.control.Label
        LowerCLEditField            matlab.ui.control.NumericEditField
        LowerCLEditFieldLabel       matlab.ui.control.Label
        CurrentFileTextArea         matlab.ui.control.TextArea
        CurrentFileTextAreaLabel    matlab.ui.control.Label
        OverwriteCPButton           matlab.ui.control.Button
        SliderCP                    matlab.ui.control.Slider
        ColEditField                matlab.ui.control.EditField
        ColEditFieldLabel           matlab.ui.control.Label
        RowEditField                matlab.ui.control.EditField
        RowEditFieldLabel           matlab.ui.control.Label
        SliderRow                   matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        SliderCol                   matlab.ui.control.Slider
        SelectAFMdatamatfileButton  matlab.ui.control.Button
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        AFMdata    % Structure containing loaded AFM data
        yl         % Handle for yline in UIAxes
        xl         % Handle for xline in UIAxes
        cpline     % Handle for contact point line in UIAxes_2
        cpline2    % (Optional) second contact point line
        fh         % File handle (full path) for the .mat file
        yUpperPWM  % Upper limit for PWM plot
        yLowerPWM  % Lower limit for PWM plot
        CurrentCP  % Current contact point value
        CurrentD   % Current D vector from CP slider
        CurrentF   % Current F vector from CP slider
        CurrentPWE % Current pointwise modulus vector
        CurrentE   % Current final modulus value
        movingMeanWindow % Window for moving mean smoothing
        windowSize2      % Window size for derivative smoothing
        hertzianFrontRemove % How much of the hertz curve to not consider.
    end

    methods (Access = private)
        function prepareAxes(app, ax)
            % Clear the axes, remove colorbar, set font, YDir, and make axis tight.
            % Remove the colorbar (if needed), set font size, YDir, and tighten axis.
            set(ax, 'FontSize', 12, 'YDir', 'normal');
            axis(ax, 'tight');
        end

        function updateMainCrosshairs(app)
            % Delete any existing crosshairs and add new ones based on slider values.
            if ~isempty(app.yl) && isgraphics(app.yl)
                delete(app.yl);
            end
            if ~isempty(app.xl) && isgraphics(app.xl)
                delete(app.xl);
            end

            app.yl = yline(app.UIAxes, round(app.SliderRow.Value));
            app.xl = xline(app.UIAxes, round(app.SliderCol.Value));
        end

        function plotMap(app, ax, data, plotTitle, cbarLabel)
            % Plot data with imagesc, set title, colormap, add colorbar, and adjust axes.
            imagesc(ax, data);
            title(ax, plotTitle);
            colormap(ax, 'parula');
            c = colorbar(ax);
            if ~isempty(cbarLabel)
                c.Label.String = cbarLabel;
            end
            caxis(ax, 'auto');
            app.prepareAxes(ax);
        end

        function updateSliderCP(app, x, currentCP)
            % Update the CP slider's limits and value based on the provided x vector.
            if isempty(x) || numel(x) < 1
                app.SliderCP.Limits = [0 1];
            else
                app.SliderCP.Limits = double([x(1) x(end)]);
            end
            [~, idx] = min(abs(x - currentCP));
            if isempty(idx) || idx < 1 || idx > numel(x)
                app.SliderCP.Value = double(sum(app.SliderCP.Limits));
            else
                app.SliderCP.Value = double(x(idx));
            end
        end

        %% Core plotting methods
        function plotCurrentMap(app)
            % Plot the main map based on the selection in the dropdown.
            value = app.MapquantityDropDown.Value;
            app.prepareAxes(app.UIAxes);
            switch value
                case "Height"
                    app.plotMap(app.UIAxes, app.AFMdata.Height_Matrix, 'Relative Height (nm)', 'nm');
                case "Quality"
                    app.plotMap(app.UIAxes, app.AFMdata.AcceptRejectMap, 'Quality: Accept (1) Reject (0)', '');
                case "Hertzian Modulus"
                    app.plotMap(app.UIAxes, app.AFMdata.HertzianModulus_Matrix, 'Hertzian Modulus (kPa)', 'kPa');
                case "infoIndent"
                    app.plotIndentInfoMap();
                    return;  % Exit since indent map plotting is handled separately.
                otherwise
                    warning("Unexpected MapquantityDropDown value.");
            end
            app.updateMainCrosshairs();
        end

        function plotIndentInfoMap(app)
            % Plot the indent info map using categorical conversion.
            app.prepareAxes(app.UIAxes);
            categories = ["Accepted", "Bad vibes", "Ambiguous CP", "Too stiff", "Probe slip", "Precontact skibidi"];
            catData = categorical(app.AFMdata.indentInfoMap, categories, 'Ordinal', false);
            numericData = double(catData);
            categoryColors = [0, 0.4470, 0.7410;
                0.8500, 0.3250, 0.0980;
                0.9290, 0.6940, 0.1250;
                0.4940, 0.1840, 0.5560;
                0.4660, 0.6740, 0.1880;
                0.3010, 0.7450, 0.9330];
            imagesc(app.UIAxes, numericData);
            colormap(app.UIAxes, categoryColors);
            caxis(app.UIAxes, [1 length(categories)]);
            c = colorbar(app.UIAxes);
            c.Ticks = 1:length(categories);
            c.TickLabels = categories;
            title(app.UIAxes, 'Indent Info Map');
            app.prepareAxes(app.UIAxes);
            app.updateMainCrosshairs();
        end

        function updateIndentInfoMap(app, newValue)
            % Update the indent info map at the current slider location.
            row = round(app.SliderRow.Value);
            col = round(app.SliderCol.Value);
            app.AFMdata.indentInfoMap(row, col) = newValue;
            indentInfoMap = app.AFMdata.indentInfoMap;
            save(app.fh, 'indentInfoMap', '-append');
            if strcmp(app.MapquantityDropDown.Value, 'InfoIndent')
                app.plotIndentInfoMap();
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectAFMdatamatfileButton
        function SelectAFMdatamatfileButtonPushed(app, event)
            addpath("C:\Users\MrBes\Documents\MATLAB\AFM_ML\AFM_ML_v6_sandbox\helperFunctions")
            [fh, p] = uigetfile();
            if isequal(fh, 0)
                return;
            end
            fullPath = fullfile(p, fh);
            app.AFMdata = load(fullPath);
            app.fh = fullPath;
            app.movingMeanWindow = 10;
            app.windowSize2 = 10;
            app.hertzianFrontRemove = 100;


            % Initialize AcceptRejectMap if missing.
            [nrows, ncols] = size(app.AFMdata.Height_Matrix);
            if ~isfield(app.AFMdata, 'AcceptRejectMap')
                AcceptRejectMap = true(nrows, ncols);
                save(app.fh, 'AcceptRejectMap', '-append');
                app.AFMdata.AcceptRejectMap = AcceptRejectMap;
            end
            % Initialize indentInfoMap if missing.
            if ~isfield(app.AFMdata, 'indentInfoMap')
                indentInfoMap = strings(nrows, ncols);
                indentInfoMap(app.AFMdata.AcceptRejectMap == true) = "Accepted";
                indentInfoMap(app.AFMdata.AcceptRejectMap == false) = "Bad vibes";
                save(app.fh, 'indentInfoMap', '-append');
                app.AFMdata.indentInfoMap = indentInfoMap;
            end

            % Initialize Hertzian Modulus if missing.
            if ~isfield(app.AFMdata, 'HertzianModulus_Matrix')
                HertzianModulus_Matrix = zeros(nrows, ncols);

                for j = 1:nrows
                    for jj = 1:ncols
                        D = app.AFMdata.D_Matrix{j,jj};
                        F = app.AFMdata.F_Matrix{j,jj};
                        R = app.AFMdata.R;
                        th = app.AFMdata.th;
                        b = app.AFMdata.v;
                        if isfield(app.AFMdata, 'TIP_IS_SPHERICAL')
                            isItSpherical = app.AFMdata.TIP_IS_SPHERICAL;
                        else
                            isItSpherical = 0; % false
                        end

                        try
                            E_app = calc_E_app(D, F, R, th, b, 'Hertz', 0, app.hertzianFrontRemove, isItSpherical);
                            E_app = E_app * 1e18 * 1e-9;  % Conversions remain unchanged
                            E_app = E_app / 1000;
                            HertzianModulus_Matrix(j,jj) = E_app;
                        catch
                            HertzianModulus_Matrix(j,jj) = nan;
                        end
                    end
                end

                save(app.fh, 'HertzianModulus_Matrix', '-append');
                app.AFMdata.HertzianModulus_Matrix = HertzianModulus_Matrix;
            end

            % Plot the current map.
            app.plotCurrentMap();

            % Set slider limits.
            app.SliderRow.Limits = [1 nrows];
            app.SliderRow.MajorTicks = 1:nrows;
            app.SliderCol.Limits = [1 ncols];
            app.SliderCol.MajorTicks = 1:ncols;

            % Reset the slider limits.
            app.SliderRow.Value = 1; %
            app.SliderCol.Value = 1;

            % Clear UIAxes_2
            cla(app.UIAxes_2)


            % Plot raw force curve in UIAxes_2.
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            yM = smooth(y, app.movingMeanWindow);
            dyM = diff(yM) ./ diff(x);
            dyM2 = smooth(dyM, app.windowSize2);
            title(app.UIAxes_2, 'Raw Force Curve');
            xlabel(app.UIAxes_2, 'Extension (nm)');
            ylabel(app.UIAxes_2, 'Deflection(nm)');
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, x, y, '*');
            plot(app.UIAxes_2, x, yM, 'k-');
            % Plot contact point (CP).
            thisCP = app.AFMdata.CP_Matrix(round(app.SliderRow.Value), round(app.SliderCol.Value));
            err = abs(x - thisCP);
            [~, ndx_CP] = min(err);
            plot(app.UIAxes_2, x(ndx_CP), y(ndx_CP), 'ko', 'markersize', 10);
            legend(app.UIAxes_2, 'Deflection', 'Smoothed', 'CP', 'location', 'best');
            app.updateMainCrosshairs();

            % Plot pointwise modulus in UIAxes_3.
            D = app.AFMdata.D_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            E = app.AFMdata.PWE_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            plot(app.UIAxes_3, D, real(E), '*');
            app.yUpperPWM = prctile(E, 95);
            app.yLowerPWM = 0;
            ylim(app.UIAxes_3, [app.yLowerPWM app.yUpperPWM]);
            ylabel(app.UIAxes_3, 'E (kPa)');
            xlabel(app.UIAxes_3, 'Depth (nm)');
            grid(app.UIAxes_3, 'on');
            app.CurrentFileTextArea.Value = fullPath;
            app.CurrentCP = thisCP;
            app.updateMainCrosshairs();


        end

        % Value changing function: SliderRow
        function SliderRowValueChanging(app, event)
            newRow = round(event.Value);
            x = app.AFMdata.Ext_Matrix{newRow, round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{newRow, round(app.SliderCol.Value)};
            yM = smooth(double(y), app.movingMeanWindow);
            dyM = diff(yM) ./ diff(x);
            dyM2 = smooth(dyM, app.windowSize2);
            hold(app.UIAxes_2, 'off');
            plot(app.UIAxes_2, x, y, '*');
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, x, yM, 'k-');
            thisCP = app.AFMdata.CP_Matrix(newRow, round(app.SliderCol.Value));
            err = abs(x - thisCP);
            [~, ndx_CP] = min(err);
            plot(app.UIAxes_2, x(ndx_CP), y(ndx_CP), 'ko', 'markersize', 10);
            legend(app.UIAxes_2, 'Deflection', 'Smoothed', 'CP', 'location', 'best');
            app.updateSliderCP(x, thisCP);
            D = app.AFMdata.D_Matrix{newRow, round(app.SliderCol.Value)};
            E = app.AFMdata.PWE_Matrix{newRow, round(app.SliderCol.Value)};
            plot(app.UIAxes_3, D, real(E), '*');
            app.yUpperPWM = prctile(E, 90);
            app.yLowerPWM = 0;
            ylim(app.UIAxes_3, [app.yLowerPWM app.yUpperPWM]);
            app.CurrentCP = thisCP;
            if ~isempty(app.yl) && isgraphics(app.yl)
                delete(app.yl);
            end
            app.yl = yline(app.UIAxes, newRow);
        end

        % Value changing function: SliderCol
        function SliderColValueChanging(app, event)
            newCol = round(event.Value);
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value), newCol};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value), newCol};
            yM = smooth(y, app.movingMeanWindow);
            dyM = diff(yM) ./ diff(x);
            dyM2 = smooth(dyM, app.windowSize2);
            hold(app.UIAxes_2, 'off');
            plot(app.UIAxes_2, x, y, '*');
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, x, yM, 'k-');
            thisCP = app.AFMdata.CP_Matrix(round(app.SliderRow.Value), newCol);
            err = abs(x - thisCP);
            [~, ndx_CP] = min(err);
            plot(app.UIAxes_2, x(ndx_CP), y(ndx_CP), 'ko', 'markersize', 10);
            legend(app.UIAxes_2, 'Deflection', 'Smoothed', 'CP', 'location', 'best');
            app.updateSliderCP(x, thisCP);
            D = app.AFMdata.D_Matrix{round(app.SliderRow.Value), newCol};
            E = app.AFMdata.PWE_Matrix{round(app.SliderRow.Value), newCol};
            plot(app.UIAxes_3, D, real(E), '*');
            app.yUpperPWM = prctile(E, 90);
            app.yLowerPWM = 0;
            ylim(app.UIAxes_3, [app.yLowerPWM app.yUpperPWM]);
            app.ColEditField.Value = num2str(newCol);
            if ~isempty(app.xl) && isgraphics(app.xl)
                delete(app.xl);
            end
            app.xl = xline(app.UIAxes, newCol);
            app.CurrentCP = thisCP;

        end

        % Value changing function: SliderCP
        function SliderCPValueChanging(app, event)
            newVal = event.Value;
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            [~, CPndx] = min(abs(x - newVal));
            if ~isempty(app.cpline) && isgraphics(app.cpline)
                delete(app.cpline);
            end

            app.cpline = xline(app.UIAxes_2, newVal);
            app.CurrentCP = x(CPndx);
        end

        % Button pushed function: OverwriteCPButton
        function OverwriteCPButtonPushed(app, event)
            newVal = app.SliderCP.Value;
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            [~, CPndx] = min(abs(x - newVal));
            h = y(CPndx:end) - y(CPndx);
            z = x(CPndx:end) - x(CPndx);

            D = z - h;
            F = h .* app.AFMdata.spring_constant;
            R = app.AFMdata.R;
            th = app.AFMdata.th;
            b = app.AFMdata.b;
            if isfield(app.AFMdata, 'TIP_IS_SPHERICAL')
                isItSpherical = app.AFMdata.TIP_IS_SPHERICAL;
            else
                isItSpherical = 0; % false
            end

            [E_app, regimeChange] = calc_E_app(D, F, R, th, b, 'pointwise', 0, app.hertzianFrontRemove, isItSpherical);
            E_app = E_app * 1e18 * 1e-9;  % Conversions remain unchanged
            E_app = E_app / 1000;
            v = app.AFMdata.v;
            E = E_app .* 2 .* (1 - v^2);
            plot(app.UIAxes_3, D, real(E), '*');
            app.yUpperPWM = prctile(E, 90);
            app.yLowerPWM = 0;
            ylim(app.UIAxes_3, [app.yLowerPWM app.yUpperPWM]);
            app.CurrentD = D;
            app.CurrentF = F;
            app.CurrentPWE = E;
            app.CurrentE = E(end);
            row = round(app.SliderRow.Value);
            col = round(app.SliderCol.Value);
            app.AFMdata.CP_Matrix(row, col) = app.CurrentCP;
            app.AFMdata.D_Matrix{row, col} = app.CurrentD;
            app.AFMdata.F_Matrix{row, col} = app.CurrentF;
            app.AFMdata.PWE_Matrix{row, col} = app.CurrentPWE;
            app.AFMdata.E_Matrix(row, col) = app.CurrentE;
            Height_Matrix = max(app.AFMdata.CP_Matrix) - app.AFMdata.CP_Matrix;
            CP_Matrix = app.AFMdata.CP_Matrix;
            D_Matrix = app.AFMdata.D_Matrix;
            F_Matrix = app.AFMdata.F_Matrix;
            PWE_Matrix = app.AFMdata.PWE_Matrix;
            E_matrix = app.AFMdata.E_Matrix;

            updatedDate = date;
            updatedTime = clock;
            save(app.fh, 'CP_Matrix', 'D_Matrix', 'F_Matrix', 'PWE_Matrix', ...
                'Height_Matrix', 'updatedDate', 'updatedTime', '-append');
            app.AFMdata = load(app.fh);
            switch app.MapquantityDropDown.Value
                case "Height"
                    app.plotMap(app.UIAxes, app.AFMdata.Height_Matrix, 'Relative Height (nm)', 'nm');
                case "Modulus"
                    app.plotMap(app.UIAxes, app.AFMdata.E_Matrix, 'Elastic Modulus (kPa)', 'kPa');
                case "Quality"
                    app.plotMap(app.UIAxes, app.AFMdata.AcceptRejectMap, 'Quality: Accept (1) Reject (0)', '');
                otherwise
                    warning("Unexpected map quantity.");
            end
            app.updateMainCrosshairs();
        end

        % Callback function
        function NewModulusMapButtonPushed(app, event)
            depthToUse = app.IndentationDepthnmEditField.Value;
            [nRows, nCols] = size(app.AFMdata.Height_Matrix);
            newModulusMap = zeros(nRows, nCols);
            for i_row = 1:nRows
                for i_col = 1:nCols
                    D = app.AFMdata.D_Matrix{i_row, i_col};
                    PWE = app.AFMdata.PWE_Matrix{i_row, i_col};
                    if isempty(D)
                        newModulusMap(i_row, i_col) = nan;
                    elseif D(end) < depthToUse
                        newModulusMap(i_row, i_col) = PWE(end);
                    else
                        [~, minIdx] = min(abs(D - depthToUse));
                        newModulusMap(i_row, i_col) = PWE(minIdx);
                    end
                end
            end
            imagesc(app.UIAxes_4, newModulusMap);
            title(app.UIAxes_4, sprintf('Modulus (kPa) @ D = %i nm', depthToUse));
            c = colorbar(app.UIAxes_4);
            c.Label.String = 'kPa';
            app.prepareAxes(app.UIAxes_4);
            caxis(app.UIAxes_4, [app.LowerCLEditField.Value app.UpperCLEditField.Value]);


        end

        % Button pushed function: UpdateClimitsButton
        function UpdateClimitsButtonPushed(app, event)
            caxis(app.UIAxes,[app.LowerCLEditField.Value app.UpperCLEditField.Value]);
        end

        % Button pushed function: AxisAutoButton
        function AxisAutoButtonPushed(app, event)
            axis(app.UIAxes_2,'tight')
            % axis(app.UIAxes2,'tight')
        end

        % Value changed function: MapquantityDropDown
        function MapquantityDropDownValueChanged(app, event)
            app.plotCurrentMap();
        end

        % Button pushed function: AcceptFCButton
        function AcceptFCButtonPushed(app, event)
            row = round(app.SliderRow.Value);
            col = round(app.SliderCol.Value);
            app.AFMdata.AcceptRejectMap(row, col) = true;
            app.AFMdata.indentInfoMap(row, col) = "Accepted";
            AcceptRejectMap = app.AFMdata.AcceptRejectMap;
            indentInfoMap = app.AFMdata.indentInfoMap;
            save(app.fh, 'AcceptRejectMap', 'indentInfoMap', '-append');
            app.plotCurrentMap();
        end

        % Button pushed function: RejectFCButton
        function RejectFCButtonPushed(app, event)
            row = round(app.SliderRow.Value);
            col = round(app.SliderCol.Value);
            app.AFMdata.AcceptRejectMap(row,col) = false;
            app.AFMdata.indentInfoMap(row,col) = "Bad vibes";
            AcceptRejectMap = app.AFMdata.AcceptRejectMap;
            indentInfoMap = app.AFMdata.indentInfoMap;
            save(app.fh,'AcceptRejectMap','indentInfoMap','-append');
            app.plotCurrentMap();

        end

        % Button pushed function: AxisAutoButton_2
        function AxisAutoButton_2Pushed(app, event)
            axis(app.UIAxes_3,'tight')
        end

        % Button pushed function: MinusOneButton
        function MinusOneButtonPushed(app, event)
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            [~, CPndx] = min(abs(x - app.CurrentCP));
            app.CurrentCP = x(CPndx - 1);
            if ~isempty(app.cpline) && isgraphics(app.cpline)
                delete(app.cpline);
            end

            app.cpline = xline(app.UIAxes_2, app.CurrentCP);
            app.SliderCP.Value = double(app.CurrentCP);
        end

        % Button pushed function: PlusOneButton
        function PlusOneButtonPushed(app, event)
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value), round(app.SliderCol.Value)};
            [~, CPndx] = min(abs(x - app.CurrentCP));
            app.CurrentCP = x(CPndx + 1);
            if ~isempty(app.cpline) && isgraphics(app.cpline)
                delete(app.cpline);
            end

            app.cpline = xline(app.UIAxes_2, app.CurrentCP);
            app.SliderCP.Value = double(app.CurrentCP);
        end

        % Button pushed function: AmbiguousCPButton
        function AmbiguousCPButtonPushed(app, event)
            app.updateIndentInfoMap("Ambiguous CP");
            app.plotCurrentMap
        end

        % Button pushed function: PrecontactskibidiButton
        function PrecontactskibidiButtonPushed(app, event)
            app.updateIndentInfoMap("Precontact skibidi");
            app.plotCurrentMap
        end

        % Button pushed function: ToostiffButton
        function ToostiffButtonPushed(app, event)
            app.updateIndentInfoMap("Too stiff");
            app.plotCurrentMap
        end

        % Button pushed function: ProbeslipButton
        function ProbeslipButtonPushed(app, event)
            app.updateIndentInfoMap("Probe slip");
            app.plotCurrentMap
        end

        % Button pushed function: BadvibesButton
        function BadvibesButtonPushed(app, event)
            app.updateIndentInfoMap("Bad vibes");
            app.plotCurrentMap
        end

        % Button pushed function: PredictCurrentButton
        function PredictCurrentButtonPushed(app, event)
            % Button pushed function: PredictCurrentButton
            method = app.RegressionPredictionMethodDropDown.Value; % Get selected method

            switch method
                case "linear-quadratic"
                    % Extract current curve data from sliders
                    row = round(app.SliderRow.Value);
                    col = round(app.SliderCol.Value);
                    xData = app.AFMdata.Ext_Matrix{row, col};
                    yData = app.AFMdata.ExtDefl_Matrix{row, col};

                    % Define hyperparameters
                    dataFraction = 0.3;
                    lb = [0, -0.5, 2, 0, 0, 0, 1];
                    ub = [1, 0.5, 2, 500, 500, 0, 1];

                    % Call external prediction function
                    predictedCP = linearquadratic_GUI(xData, yData, dataFraction, lb, ub);

                case "SNAP"
                    % Extract current curve data from sliders
                    row = round(app.SliderRow.Value);
                    col = round(app.SliderCol.Value);
                    xData = app.AFMdata.Ext_Matrix{row, col};
                    yData = app.AFMdata.ExtDefl_Matrix{row, col};
                    springConstant = app.AFMdata.spring_constant;
                    v = app.AFMdata.v;
                    th = app.AFMdata.th;
                    b = app.AFMdata.b;
                    R = app.AFMdata.R;

                    % Define hyperparameters
                    maxIter = 20;
                    offsetFraction = 0.3;
                    deflThreshold = 2;  % nm
                    deflFitRange = [0, 15];  % nm
                    plotDebug = false;  % Change to true for debugging

                    % Call external prediction function
                    predictedCP = SNAP_GUI(xData, yData, springConstant, v, th, ...
                        maxIter, offsetFraction, deflThreshold, deflFitRange, plotDebug, b, R);

                case "Neural Network"
                    % Define directory containing trained models
                    modelDir = "C:\Users\MrBes\Documents\MATLAB\AFM_ML\AFM_ML_v6_sandbox\training\trainedRegressionModels";

                    if app.NetworkDropDown.Visible
                        % Dropdown is already visible, just call prediction function
                        selectedModel = fullfile(modelDir, app.NetworkDropDown.Value);
                        row = round(app.SliderRow.Value);
                        col = round(app.SliderCol.Value);
                        
                        xData = app.AFMdata.Ext_Matrix{row, col};
                        yData = app.AFMdata.ExtDefl_Matrix{row, col};

                        % Call external Neural Network prediction function
                        predictedCP = predict_NN_GUI(xData, yData, selectedModel);

                    else
                        % Make NetworkDropDown visible and load models
                        app.NetworkDropDown.Visible = true;

                        % Get list of .mat files in the directory
                        modelFiles = dir(fullfile(modelDir, '*.mat'));
                        modelNames = {modelFiles.name}; % Extract filenames

                        if isempty(modelNames)
                            modelNames = {'No Models Found'};
                        end

                        % Update the dropdown options
                        app.NetworkDropDown.Items = modelNames;

                        % If models exist, use the first one for prediction
                        if ~isempty(modelFiles)
                            firstModel = fullfile(modelDir, modelNames{1});
                            row = round(app.SliderRow.Value);
                            col = round(app.SliderCol.Value);
                            xData = app.AFMdata.Ext_Matrix{row, col};
                            yData = app.AFMdata.ExtDefl_Matrix{row, col};

                            % Call external Neural Network prediction function
                            predictedCP = predict_NN_GUI(xData, yData, firstModel);

                        end
                    end

                otherwise
                    warning("Prediction method not implemented yet.");
                    return;
            end

            % Update GUI with the predicted CP
            app.SliderCP.Value = predictedCP;
            app.CurrentCP = predictedCP;

            % Update crosshairs
            if ~isempty(app.cpline) && isgraphics(app.cpline)
                delete(app.cpline);
            end
            app.cpline = xline(app.UIAxes_2, predictedCP, '--r', 'Predicted CP');


        end

        % Button pushed function: PredictCurrentButton_2
        function PredictCurrentButton_2Pushed(app, event)
            method = app.ClassificationPredictionMethodDropDown.Value; % Get method, which for now should just be neural network
            switch method
                case "Neural Network"
                    % Define directory containing trained models
                    modelDir = "C:\Users\MrBes\Documents\MATLAB\AFM_ML\AFM_ML_v6_sandbox\training\trainedClassificationModels";

                    if app.NetworkDropDown_2.Visible
                        % Dropdown is already visible, just call prediction function
                        selectedModel = fullfile(modelDir, app.NetworkDropDown_2.Value);
                        row = round(app.SliderRow.Value);
                        col = round(app.SliderCol.Value);

                        xData = app.AFMdata.Ext_Matrix{row, col};
                        yData = app.AFMdata.ExtDefl_Matrix{row, col};

                        % Call external Neural Network prediction function
                        predictedClassification = predict_NN_GUI_classification(xData, yData, selectedModel);

                    else
                        % Make NetworkDropDown visible and load models
                        app.NetworkDropDown_2.Visible = true;
                        app.ThresholdEditField.Visible = true;

                        % Get list of .mat files in the directory
                        modelFiles = dir(fullfile(modelDir, '*.mat'));
                        modelNames = {modelFiles.name}; % Extract filenames

                        if isempty(modelNames)
                            modelNames = {'No Models Found'};
                        end

                        % Update the dropdown options
                        app.NetworkDropDown_2.Items = modelNames;

                        % If models exist, use the first one for prediction
                        if ~isempty(modelFiles)
                            firstModel = fullfile(modelDir, modelNames{1});
                            row = round(app.SliderRow.Value);
                            col = round(app.SliderCol.Value);
                            xData = app.AFMdata.Ext_Matrix{row, col};
                            yData = app.AFMdata.ExtDefl_Matrix{row, col};

                            % Call external Neural Network prediction function
                            predictedClassification = predict_NN_GUI_classification(xData, yData, firstModel);

                        end
                    end

                    if predictedClassification >= app.ThresholdEditField.Value
                        app.QualityPredictionEditField.Value = 'REJECT! ):'
                    else
                        app.QualityPredictionEditField.Value = 'ACCEPT! :)'
                    end


                otherwise
                    warning("Prediction method not implemented yet.");
                    return;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Pointwise Modulus (kPa)')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.SelectAFMdatamatfileButton = uibutton(app.UIFigure, 'push');
            app.SelectAFMdatamatfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAFMdatamatfileButtonPushed, true);
            app.SelectAFMdatamatfileButton.Text = 'Select AFM data (.mat file)';
            app.SliderCol = uislider(app.UIFigure);
            app.SliderCol.ValueChangingFcn = createCallbackFcn(app, @SliderColValueChanging, true);
            app.SliderCol.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.SliderRow = uislider(app.UIFigure);
            app.SliderRow.ValueChangingFcn = createCallbackFcn(app, @SliderRowValueChanging, true);
            app.SliderRow.Value = 1;
            app.RowEditFieldLabel = uilabel(app.UIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.UIFigure, 'text');
            app.RowEditField.Value = '1';
            app.ColEditFieldLabel = uilabel(app.UIFigure);
            app.ColEditFieldLabel.Text = 'Col';
            app.ColEditField = uieditfield(app.UIFigure, 'text');
            app.ColEditField.Value = '1';
            app.SliderCP = uislider(app.UIFigure);
            app.SliderCP.Limits = [-10.4 100];
            app.SliderCP.ValueChangingFcn = createCallbackFcn(app, @SliderCPValueChanging, true);
            app.OverwriteCPButton = uibutton(app.UIFigure, 'push');
            app.OverwriteCPButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteCPButtonPushed, true);
            app.OverwriteCPButton.Text = {'Overwrite CP'; ''};
            app.CurrentFileTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentFileTextAreaLabel.Text = 'Current File';
            app.CurrentFileTextArea = uitextarea(app.UIFigure);
            app.LowerCLEditFieldLabel = uilabel(app.UIFigure);
            app.LowerCLEditFieldLabel.Text = 'Lower CL ';
            app.LowerCLEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperCLEditFieldLabel = uilabel(app.UIFigure);
            app.UpperCLEditFieldLabel.Text = 'Upper CL';
            app.UpperCLEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperCLEditField.Value = 15;
            app.UpdateClimitsButton = uibutton(app.UIFigure, 'push');
            app.UpdateClimitsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateClimitsButtonPushed, true);
            app.UpdateClimitsButton.Text = 'Update Climits';
            app.AxisAutoButton = uibutton(app.UIFigure, 'push');
            app.AxisAutoButton.ButtonPushedFcn = createCallbackFcn(app, @AxisAutoButtonPushed, true);
            app.AxisAutoButton.Text = {'Axis Auto'; ''};
            app.MapquantityDropDownLabel = uilabel(app.UIFigure);
            app.MapquantityDropDownLabel.Text = 'Map quantity';
            app.MapquantityDropDown = uidropdown(app.UIFigure);
            app.MapquantityDropDown.Items = {'Height', 'Hertzian Modulus', 'Quality', 'infoIndent'};
            app.MapquantityDropDown.ValueChangedFcn = createCallbackFcn(app, @MapquantityDropDownValueChanged, true);
            app.MapquantityDropDown.Value = 'Height';
            app.AcceptFCButton = uibutton(app.UIFigure, 'push');
            app.AcceptFCButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptFCButtonPushed, true);
            app.AcceptFCButton.Text = 'Accept FC';
            app.RejectFCButton = uibutton(app.UIFigure, 'push');
            app.RejectFCButton.ButtonPushedFcn = createCallbackFcn(app, @RejectFCButtonPushed, true);
            app.RejectFCButton.Text = 'Reject FC';
            app.AxisAutoButton_2 = uibutton(app.UIFigure, 'push');
            app.AxisAutoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AxisAutoButton_2Pushed, true);
            app.AxisAutoButton_2.Text = {'Axis Auto'; ''};
            app.MinusOneButton = uibutton(app.UIFigure, 'push');
            app.MinusOneButton.ButtonPushedFcn = createCallbackFcn(app, @MinusOneButtonPushed, true);
            app.MinusOneButton.Text = '-1';
            app.PlusOneButton = uibutton(app.UIFigure, 'push');
            app.PlusOneButton.ButtonPushedFcn = createCallbackFcn(app, @PlusOneButtonPushed, true);
            app.PlusOneButton.Text = '+1';
            app.AmbiguousCPButton = uibutton(app.UIFigure, 'push');
            app.AmbiguousCPButton.ButtonPushedFcn = createCallbackFcn(app, @AmbiguousCPButtonPushed, true);
            app.AmbiguousCPButton.Text = 'Ambiguous CP';
            app.ToostiffButton = uibutton(app.UIFigure, 'push');
            app.ToostiffButton.ButtonPushedFcn = createCallbackFcn(app, @ToostiffButtonPushed, true);
            app.ToostiffButton.Text = 'Too stiff';
            app.ProbeslipButton = uibutton(app.UIFigure, 'push');
            app.ProbeslipButton.ButtonPushedFcn = createCallbackFcn(app, @ProbeslipButtonPushed, true);
            app.ProbeslipButton.Text = 'Probe slip';
            app.BadvibesButton = uibutton(app.UIFigure, 'push');
            app.BadvibesButton.ButtonPushedFcn = createCallbackFcn(app, @BadvibesButtonPushed, true);
            app.BadvibesButton.Text = 'Bad vibes';
            app.PrecontactskibidiButton = uibutton(app.UIFigure, 'push');
            app.PrecontactskibidiButton.ButtonPushedFcn = createCallbackFcn(app, @PrecontactskibidiButtonPushed, true);
            app.PrecontactskibidiButton.Text = 'Precontact skibidi';
            app.RegressionPredictionMethodDropDownLabel = uilabel(app.UIFigure);
            app.RegressionPredictionMethodDropDownLabel.Text = 'Regression Prediction Method';
            app.RegressionPredictionMethodDropDown = uidropdown(app.UIFigure);
            app.RegressionPredictionMethodDropDown.Items = {'linear-quadratic', 'SNAP', 'Neural Network'};
            app.RegressionPredictionMethodDropDown.Value = 'linear-quadratic';
            app.PredictCurrentButton = uibutton(app.UIFigure, 'push');
            app.PredictCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PredictCurrentButtonPushed, true);
            app.PredictCurrentButton.Text = 'Predict Current';
            app.NetworkDropDownLabel = uilabel(app.UIFigure);
            app.NetworkDropDownLabel.Visible = 'off';
            app.NetworkDropDownLabel.Text = 'Network';
            app.NetworkDropDown = uidropdown(app.UIFigure);
            app.NetworkDropDown.Items = {};
            app.NetworkDropDown.Visible = 'off';
            app.NetworkDropDown.Value = {};
            app.ClassificationPredictionMethodDropDownLabel = uilabel(app.UIFigure);
            app.ClassificationPredictionMethodDropDownLabel.Text = 'Classification Prediction Method';
            app.ClassificationPredictionMethodDropDown = uidropdown(app.UIFigure);
            app.ClassificationPredictionMethodDropDown.Items = {'Neural Network'};
            app.ClassificationPredictionMethodDropDown.Value = 'Neural Network';
            app.NetworkDropDown_2Label = uilabel(app.UIFigure);
            app.NetworkDropDown_2Label.Visible = 'off';
            app.NetworkDropDown_2Label.Text = 'Network';
            app.NetworkDropDown_2 = uidropdown(app.UIFigure);
            app.NetworkDropDown_2.Items = {};
            app.NetworkDropDown_2.Visible = 'off';
            app.NetworkDropDown_2.Value = {};
            app.PredictCurrentButton_2 = uibutton(app.UIFigure, 'push');
            app.PredictCurrentButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredictCurrentButton_2Pushed, true);
            app.PredictCurrentButton_2.Text = 'Predict Current';
            app.QualityPredictionEditFieldLabel = uilabel(app.UIFigure);
            app.QualityPredictionEditFieldLabel.Text = 'Quality Prediction:';
            app.QualityPredictionEditField = uieditfield(app.UIFigure, 'text');
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end